classdef app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure           matlab.ui.Figure
        Switch             matlab.ui.control.RockerSwitch
        SwitchLabel        matlab.ui.control.Label
        WidthShrinkButton  matlab.ui.control.Button
        LoadButton         matlab.ui.control.Button
        EnergyAxes         matlab.ui.control.UIAxes
        CurrentImageAxes   matlab.ui.control.UIAxes
        OriginalImageAxes  matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        original % 图片
        current % 当前图片(CarveImage)
        cur_seam % 当前的seam
    end
    
    methods (Access = private)
        
        function updateImage(app, filename)
            try
                im = imread(filename);
            catch ME
                % If problem reading image, display error message
                uialert(app.UIFigure, ME.message, 'Image Error');
                return;
            end
            app.original = im;
            imagesc(app.OriginalImageAxes,im);
            app.current = CarveImage(im);
            app.refreshImage();
        end
    end
    
    methods (Access = public)
        
        function refreshImage(app)
            imagesc(app.EnergyAxes, app.current.energy_map);
            app.cur_seam = app.current.computeVerticalSeam();
            imagesc(app.CurrentImageAxes, app.current.highlightVerticalSeam(app.cur_seam));
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            filename = 'catlogo.jpg';
            app.updateImage(filename);
        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            % Display uigetfile dialog
            filterspec = {'*.jpg;*.tif;*.png;*.gif','All Image Files'};
            [f, p] = uigetfile(filterspec);
            
            % Make sure user didn't cancel uigetfile dialog
            if (ischar(p))
               fname = [p f];
               updateImage(app, fname);
            end
        end

        % Button pushed function: WidthShrinkButton
        function WidthShrinkButtonPushed(app, event)
            newImg = app.current.removeVerticalSeam(app.cur_seam);
            app.current = newImg;
            app.refreshImage();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.OriginalImageAxes = uiaxes(app.UIFigure);
            app.OriginalImageAxes.XTick = [];
            app.OriginalImageAxes.XTickLabel = {'[ ]'};
            app.OriginalImageAxes.YTick = [];
            app.CurrentImageAxes = uiaxes(app.UIFigure);
            app.CurrentImageAxes.XTick = [];
            app.CurrentImageAxes.XTickLabel = {'[ ]'};
            app.CurrentImageAxes.YTick = [];
            app.EnergyAxes = uiaxes(app.UIFigure);
            app.EnergyAxes.XTick = [];
            app.EnergyAxes.XTickLabel = {'[ ]'};
            app.EnergyAxes.YTick = [];
            app.LoadButton = uibutton(app.UIFigure, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load Custom Image';
            app.WidthShrinkButton = uibutton(app.UIFigure, 'push');
            app.WidthShrinkButton.ButtonPushedFcn = createCallbackFcn(app, @WidthShrinkButtonPushed, true);
            app.WidthShrinkButton.Text = 'Width Shrink';
            app.SwitchLabel = uilabel(app.UIFigure);
            app.SwitchLabel.Text = 'Switch';
            app.Switch = uiswitch(app.UIFigure, 'rocker');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end